classdef Sonkyo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Sonkyobenchmark02UIFigure      matlab.ui.Figure
        SubmitButton                   matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        SelectmodelandanalysisPanel    matlab.ui.container.Panel
        ScenarioListBoxLabel           matlab.ui.control.Label
        ScenarioListBox                matlab.ui.control.ListBox
        SensorsListBoxLabel            matlab.ui.control.Label
        SensorsListBox                 matlab.ui.control.ListBox
        Crack1ButtonGroup              matlab.ui.container.ButtonGroup
        Button                         matlab.ui.control.RadioButton
        Button_2                       matlab.ui.control.RadioButton
        Button_3                       matlab.ui.control.RadioButton
        Crack2ButtonGroup              matlab.ui.container.ButtonGroup
        Button_4                       matlab.ui.control.RadioButton
        Button_5                       matlab.ui.control.RadioButton
        Button_6                       matlab.ui.control.RadioButton
        Crack3ButtonGroup              matlab.ui.container.ButtonGroup
        Button_7                       matlab.ui.control.RadioButton
        Button_8                       matlab.ui.control.RadioButton
        Button_9                       matlab.ui.control.RadioButton
        DegreeofdamageLabel            matlab.ui.control.Label
        AnalysisButtonGroup            matlab.ui.container.ButtonGroup
        ModalButton                    matlab.ui.control.RadioButton
        TimehistoryButton              matlab.ui.control.RadioButton
        OutputfileEditFieldLabel       matlab.ui.control.Label
        OutputfileEditField            matlab.ui.control.EditField
        ModelpreviewPanel              matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        SpecifyanalysissettingsPanel   matlab.ui.container.Panel
        OperationalconditionsLabel     matlab.ui.control.Label
        TemperatureCEditFieldLabel     matlab.ui.control.Label
        TemperatureCEditField          matlab.ui.control.NumericEditField
        PitchangledegEditField_2Label  matlab.ui.control.Label
        PitchangledegEditField_2       matlab.ui.control.NumericEditField
        RotorspeedrpmEditField_2Label  matlab.ui.control.Label
        RotorspeedrpmEditField_2       matlab.ui.control.NumericEditField
        WindspeedmsEditFieldLabel      matlab.ui.control.Label
        WindspeedmsEditField           matlab.ui.control.NumericEditField
        LoadseedEditFieldLabel         matlab.ui.control.Label
        LoadseedEditField              matlab.ui.control.NumericEditField
        TimeperiodEditFieldLabel       matlab.ui.control.Label
        TimeperiodEditField            matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel         matlab.ui.control.Label
        StepsizeEditField              matlab.ui.control.NumericEditField
        AlphaEditFieldLabel            matlab.ui.control.Label
        AlphaEditField                 matlab.ui.control.NumericEditField
        BetaEditFieldLabel             matlab.ui.control.Label
        BetaEditField                  matlab.ui.control.NumericEditField
        TimehistorysettingsLabel       matlab.ui.control.Label
        DampingLabel                   matlab.ui.control.Label
        MessagesPanel                  matlab.ui.container.Panel
        TextArea                       matlab.ui.control.TextArea
    end


    properties (Access = private)
        DamagePlots % The plots of damage scenarios
        SensorSetup % The plots for different sensor set-ups
        SensorIndex % The row index of sensors according to the set-up
        Sensors     % The sensor coordinates
        Crack1      % Crack 1, degree of damage
        Crack2      % Crack 2, degree of damage
        Crack3      % Crack 3, degree of damage
        Data        % 
    end

    methods (Access = private)
    
        function SwitchCrack1Buttons(app, status)
            set(app.Button, 'Enable', status);
            set(app.Button_2, 'Enable', status);
            set(app.Button_3, 'Enable', status);
        end
    
        function SwitchCrack2Buttons(app, status)
            set(app.Button_4, 'Enable', status);
            set(app.Button_5, 'Enable', status);
            set(app.Button_6, 'Enable', status);
        end
    
        function SwitchCrack3Buttons(app, status)
            set(app.Button_7, 'Enable', status);
            set(app.Button_8, 'Enable', status);
            set(app.Button_9, 'Enable', status);
        end
        
        function SwitchAnalysisFields(app, status)
            
            set(app.PitchangledegEditField_2Label, 'Enable', status);
            set(app.PitchangledegEditField_2, 'Enable', status);
            set(app.WindspeedmsEditFieldLabel, 'Enable', status);
            set(app.WindspeedmsEditField, 'Enable', status);
            set(app.LoadseedEditFieldLabel, 'Enable', status);
            set(app.LoadseedEditField, 'Enable', status);
            
            set(app.TimehistorysettingsLabel, 'Enable', status);
            set(app.TimeperiodEditFieldLabel, 'Enable', status);
            set(app.TimeperiodEditField, 'Enable', status);
            set(app.StepsizeEditFieldLabel, 'Enable', status);
            set(app.StepsizeEditField, 'Enable', status);
            
            set(app.DampingLabel, 'Enable', status);
            set(app.AlphaEditFieldLabel, 'Enable', status);
            set(app.AlphaEditField, 'Enable', status);
            set(app.BetaEditFieldLabel, 'Enable', status);
            set(app.BetaEditField, 'Enable', status);
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Plot blade sketch
            app.Data = load('appData.mat');
            
            x = app.Data.appData.blade(:, 1);
            y = app.Data.appData.blade(:, 2);
            plot(app.UIAxes, (-x+800)*1.005, y*1.1-10, 'k');
            app.UIAxes.XLim = [-50 1800];
            app.UIAxes.YLim = [-300 300];
            axis(app.UIAxes, 'equal');
            hold(app.UIAxes);
            % grid(app.UIAxes);

            % Plot measurement points
            app.Sensors = app.Data.appData.sensors;
            app.SensorSetup = plot(app.UIAxes, app.Sensors.coordinates(:, 1), app.Sensors.coordinates(:, 2), '.r');
            % text(app.UIAxes, -x.outputX+800, y.outputY, num2cell(1:length(x.outputX)), 'FontSize', 8, 'HorizontalAlignment', 'left');

            % Plot boundary conditions
            x = [30, 100, 30, 100];
            y = [30, 30, 95, 95];
            plot(app.UIAxes, x, y, '.k', 'Markersize', 12);
            app.UIAxes.YLim = [-300 300];
            
            % Disable crack radio buttons
            app.SwitchCrack1Buttons('off');
            app.SwitchCrack2Buttons('off');
            app.SwitchCrack3Buttons('off');
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.delete;
        end

        % Value changed function: ScenarioListBox
        function ScenarioListBoxValueChanged(app, event)
            value = app.ScenarioListBox.Value;
            
            % Crack lengths 5cm, 10cm and 15cm are store in rows 1, 2 and 3 respectively
            crack1.x = [475.8349, 475.9823; 475.828979, 476.110291; 475.878357, 476.269714]-800;
            crack1.y = [14.95836,-35.4319; 39.9077, -60.595108; 64.386215, -85.621109];
            
            % Crack lengths 5cm, 10cm and 15cm are store in rows 1, 2 and 3 respectively
            crack2.x = [274.327942, 273.952942; 274.327942, 273.685913; 274.327942, 273.546326]-800;
            crack2.y = [-109.025772,-62.472916; -109.025772, -15.189101; -109.025772, 32.146713];
            
            % Crack lengths 5cm, 10cm and 15cm are store in rows 1, 2 and 3 respectively
            crack3.x = [-75.170685, -75.039063; -75.195953, -74.940735; -75.188232, -74.823349]-800;
            crack3.y = [30.055649, -12.531875; 51.224838,-33.729507; 72.067123,-54.768936];
            
            % Load damage scenarios
            scenarios = app.Data.scenarios;
            
            % Delete existing plots
            for j=length(app.DamagePlots):-1:1
                delete(app.DamagePlots(j))
                app.DamagePlots(j) = [];
            end

            % Choose crack color
            color = [.0 .7 .2];

            function PlotCrackNames(app, scenarios, crack1, crack2, crack3)
                if crack1 == true
                    x1 = sum(scenarios.damage.crack1.x(1, :))/2+5; y1 = sum(scenarios.damage.crack1.y(1, :))/2; x2 = x1+80; y2 = -200;
                    app.DamagePlots(end+1) = plot(app.UIAxes, [x1, x2], [y1, y2], 'color', 'black', 'Linewidth', 0.2);
                    app.DamagePlots(end+1) = text(app.UIAxes, x2+5, y2, 'Crack 1');
                end
                
                if crack2 == true
                    x1 = sum(scenarios.damage.crack2.x(1, :))/2+5; y1 = sum(scenarios.damage.crack2.y(1, :))/2; x2 = x1+80; y2 = -200;
                    app.DamagePlots(end+1) = plot(app.UIAxes, [x1, x2], [y1, y2], 'color', 'black', 'Linewidth', 0.5);
                    app.DamagePlots(end+1) = text(app.UIAxes, x2+5, y2, 'Crack 2');
                end
                
                if crack3 == true
                    x1 = sum(scenarios.damage.crack3.x(1, :))/2+5; y1 = sum(scenarios.damage.crack3.y(1, :))/2; x2 = x1+120; y2 = -200;
                    app.DamagePlots(end+1) = plot(app.UIAxes, [x1, x2], [y1, y2], 'color', 'black');
                    app.DamagePlots(end+1) = text(app.UIAxes, x2+5, y2, 'Crack 3');
                end
                
            end
        
            function PlotMassLabel(app, scenarios)
                x1 = sum(scenarios.damage.mass1(:, 1))/length(scenarios.damage.mass1(:, 1)); x2 = x1+80;
                y1 = sum(scenarios.damage.mass1(:, 2))/length(scenarios.damage.mass1(:, 2)); y2 = -200;
                app.DamagePlots(end+1) = plot(app.UIAxes, [x1, x2], [y1, y2], 'color', 'black');
                app.DamagePlots(end+1) = text(app.UIAxes, x2+5, y2, 'Added mass');
            end

            switch value
                case 'Reference'
                    app.SwitchCrack1Buttons('off'); app.SwitchCrack2Buttons('off'); app.SwitchCrack3Buttons('off');
                    message = 'Healthy state';
                case 'Damage A'
                    app.SwitchCrack1Buttons('off'); app.SwitchCrack2Buttons('off'); app.SwitchCrack3Buttons('off');
                    app.DamagePlots(1) = plot(app.UIAxes, scenarios.damage.mass1(:, 1), scenarios.damage.mass1(:, 2), '.b');
                    set(app.DamagePlots(1), 'MarkerSize', 3);
                    PlotMassLabel(app, scenarios);
                    message = 'Added mass 1 x 44 gr';
                case 'Damage B'
                    app.SwitchCrack1Buttons('off'); app.SwitchCrack2Buttons('off'); app.SwitchCrack3Buttons('off');
                    app.DamagePlots(1) = plot(app.UIAxes, scenarios.damage.mass2(:, 1), scenarios.damage.mass2(:, 2), '.b');
                    set(app.DamagePlots(1), 'MarkerSize', 3);
                    PlotMassLabel(app, scenarios);
                    message = 'Added mass 2 x 44 gr';
                case 'Damage C'
                    app.SwitchCrack1Buttons('off'); app.SwitchCrack2Buttons('off'); app.SwitchCrack3Buttons('off');
                    app.DamagePlots(1) = plot(app.UIAxes, scenarios.damage.mass3(:, 1), scenarios.damage.mass3(:, 2), '.b');
                    set(app.DamagePlots(1), 'MarkerSize', 3);
                    PlotMassLabel(app, scenarios);
                    message = 'Added mass 3 x 44 gr';
                case 'Damage D'
                    app.SwitchCrack1Buttons('on'); app.SwitchCrack2Buttons('off'); app.SwitchCrack3Buttons('off');
                    app.DamagePlots(1) = plot(app.UIAxes, scenarios.damage.crack1.x(1, :), scenarios.damage.crack1.y(1, :), 'color', color);
                    message = {'Crack 1: length = 5cm'};
                    PlotCrackNames(app, scenarios, 1, 0, 0);
                case 'Damage E'
                    app.SwitchCrack1Buttons('on'); app.SwitchCrack2Buttons('on'); app.SwitchCrack3Buttons('off');
                    app.DamagePlots(1) = plot(app.UIAxes, scenarios.damage.crack1.x(1, :), scenarios.damage.crack1.y(1, :), 'color', color);
                    app.DamagePlots(2) = plot(app.UIAxes, scenarios.damage.crack2.x(1, :), scenarios.damage.crack2.y(1, :), 'color', color);
                    message = {'Crack 1: length = 5cm', 'Crack 2: length = 5cm'};
                    PlotCrackNames(app, scenarios, 1, 1, 0);
                case 'Damage F'
                    app.SwitchCrack1Buttons('on'); app.SwitchCrack2Buttons('on'); app.SwitchCrack3Buttons('on');
                    app.DamagePlots(1) = plot(app.UIAxes, scenarios.damage.crack1.x(1, :), scenarios.damage.crack1.y(1, :), 'color', color);
                    app.DamagePlots(2) = plot(app.UIAxes, scenarios.damage.crack2.x(1, :), scenarios.damage.crack2.y(1, :), 'color', color);
                    app.DamagePlots(3) = plot(app.UIAxes, scenarios.damage.crack3.x(1, :), scenarios.damage.crack3.y(1, :), 'color', color);
                    message = {'Crack 1: length = 5cm', 'Crack 2: length = 5cm', 'Crack 3: length = 5cm'};
                    PlotCrackNames(app, scenarios, 1, 1, 1);
                case 'Damage G'
                    app.SwitchCrack1Buttons('on'); app.SwitchCrack2Buttons('on'); app.SwitchCrack3Buttons('on');
                    app.DamagePlots(1) = plot(app.UIAxes, scenarios.damage.crack1.x(2, :), scenarios.damage.crack1.y(2, :), 'color', color);
                    app.DamagePlots(2) = plot(app.UIAxes, scenarios.damage.crack2.x(1, :), scenarios.damage.crack2.y(1, :), 'color', color);
                    app.DamagePlots(3) = plot(app.UIAxes, scenarios.damage.crack3.x(1, :), scenarios.damage.crack3.y(1, :), 'color', color);
                    message = {'Crack 1: length = 10cm', 'Crack 2: length = 5cm', 'Crack 3: length = 5cm'};
                    PlotCrackNames(app, scenarios, 1, 1, 1);
                case 'Damage H'
                    app.SwitchCrack1Buttons('on'); app.SwitchCrack2Buttons('on'); app.SwitchCrack3Buttons('on');
                    app.DamagePlots(1) = plot(app.UIAxes, scenarios.damage.crack1.x(2, :), scenarios.damage.crack1.y(2, :), 'color', color);
                    app.DamagePlots(2) = plot(app.UIAxes, scenarios.damage.crack2.x(2, :), scenarios.damage.crack2.y(2, :), 'color', color);
                    app.DamagePlots(3) = plot(app.UIAxes, scenarios.damage.crack3.x(1, :), scenarios.damage.crack3.y(1, :), 'color', color);
                    message = {'Crack 1: length = 10cm', 'Crack 2: length = 10cm', 'Crack 3: length = 5cm'};
                    PlotCrackNames(app, scenarios, 1, 1, 1);
                case 'Damage I'
                    app.SwitchCrack1Buttons('on'); app.SwitchCrack2Buttons('on'); app.SwitchCrack3Buttons('on');
                    app.DamagePlots(1) = plot(app.UIAxes, scenarios.damage.crack1.x(2, :), scenarios.damage.crack1.y(2, :), 'color', color);
                    app.DamagePlots(2) = plot(app.UIAxes, scenarios.damage.crack2.x(2, :), scenarios.damage.crack2.y(2, :), 'color', color);
                    app.DamagePlots(3) = plot(app.UIAxes, scenarios.damage.crack3.x(2, :), scenarios.damage.crack3.y(2, :), 'color', color);
                    message = {'Crack 1: length = 10cm', 'Crack 2: length = 10cm', 'Crack 3: length = 10cm'};
                    PlotCrackNames(app, scenarios, 1, 1, 1);
                case 'Damage J'
                    app.SwitchCrack1Buttons('on'); app.SwitchCrack2Buttons('on'); app.SwitchCrack3Buttons('on');
                    app.DamagePlots(1) = plot(app.UIAxes, scenarios.damage.crack1.x(3, :), scenarios.damage.crack1.y(3, :), 'color', color);
                    app.DamagePlots(2) = plot(app.UIAxes, scenarios.damage.crack2.x(2, :), scenarios.damage.crack2.y(2, :), 'color', color);
                    app.DamagePlots(3) = plot(app.UIAxes, scenarios.damage.crack3.x(2, :), scenarios.damage.crack3.y(2, :), 'color', color);
                    message = {'Crack 1: length = 15cm', 'Crack 2: length = 10cm', 'Crack 3: length = 10cm'};
                    PlotCrackNames(app, scenarios, 1, 1, 1);
                case 'Damage K'
                    app.SwitchCrack1Buttons('on'); app.SwitchCrack2Buttons('on'); app.SwitchCrack3Buttons('on');
                    app.DamagePlots(1) = plot(app.UIAxes, scenarios.damage.crack1.x(3, :), scenarios.damage.crack1.y(3, :), 'color', color);
                    app.DamagePlots(2) = plot(app.UIAxes, scenarios.damage.crack2.x(3, :), scenarios.damage.crack2.y(3, :), 'color', color);
                    app.DamagePlots(3) = plot(app.UIAxes, scenarios.damage.crack3.x(2, :), scenarios.damage.crack3.y(2, :), 'color', color);
                    message = {'Crack 1: length = 15cm', 'Crack 2: length = 15cm', 'Crack 3: length = 10cm'};
                    PlotCrackNames(app, scenarios, 1, 1, 1);
                case 'Damage L'
                    app.SwitchCrack1Buttons('on'); app.SwitchCrack2Buttons('on'); app.SwitchCrack3Buttons('on');
                    app.DamagePlots(1) = plot(app.UIAxes, scenarios.damage.crack1.x(3, :), scenarios.damage.crack1.y(3, :), 'color', color);
                    app.DamagePlots(2) = plot(app.UIAxes, scenarios.damage.crack2.x(3, :), scenarios.damage.crack2.y(3, :), 'color', color);
                    app.DamagePlots(3) = plot(app.UIAxes, scenarios.damage.crack3.x(3, :), scenarios.damage.crack3.y(3, :), 'color', color);
                    message = {'Crack 1: length = 15cm', 'Crack 2: length = 15cm', 'Crack 3: length = 15cm'};
                    PlotCrackNames(app, scenarios, 1, 1, 1);
            end
            
            app.UIAxes.YLim = [-300 300];
            % app.DescriptionTextAreaValueChanged(message);
        end

        % Callback function
        function DescriptionTextAreaValueChanged(app, event)
            % value = app.DescriptionTextArea.Value;
            app.DescriptionTextArea.Value = event;
        end

        % Selection changed function: Crack1ButtonGroup
        function Crack1ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Crack1ButtonGroup.SelectedObject;
            app.Crack1 = str2double(selectedButton.Text(1:2));
        end

        % Value changed function: SensorsListBox
        function SensorsListBoxValueChanged(app, event)
            value = app.SensorsListBox.Value;
            
            % Delete existing plots
            delete(app.SensorSetup)
            
            % Construct index matrix
            index = [[1; 1; 2; 3; 4; 5] repmat([1 2 3 4 5 6]', 1, 40)+repmat(5:6:244, 6, 1)];
            
            switch value
                case 'All sensors'
                    app.SensorSetup = plot(app.UIAxes, app.Sensors.coordinates(:, 1), app.Sensors.coordinates(:, 2), '.r');
                case 'Set-up 1'
                    index = index(1:2:size(index, 1), :); index(2) = 3; index(3) = 5;
                    app.SensorSetup = plot(app.UIAxes, app.Sensors.coordinates(index, 1), app.Sensors.coordinates(index, 2), '.r');
                    app.SensorIndex = index;
                case 'Set-up 2'
                    index = index(:, 2:2:size(index, 2));
                    app.SensorSetup = plot(app.UIAxes, app.Sensors.coordinates(index, 1), app.Sensors.coordinates(index, 2), '.r');
                    app.SensorIndex = index;
                case 'Set-up 3'
                    index = index(1:2:size(index, 1), 2:2:size(index, 2));
                    app.SensorSetup = plot(app.UIAxes, app.Sensors.coordinates(index, 1), app.Sensors.coordinates(index, 2), '.r');
                    app.SensorIndex = index;
                case 'Set-up 4'
                    index = index(1:2:size(index, 1), 2:4:size(index, 2));
                    app.SensorSetup = plot(app.UIAxes, app.Sensors.coordinates(index, 1), app.Sensors.coordinates(index, 2), '.r');
                    app.SensorIndex = index;
                case 'Set-up 5'
                    index = index(1:4:size(index, 1), 2:4:size(index, 2));
                    app.SensorSetup = plot(app.UIAxes, app.Sensors.coordinates(index, 1), app.Sensors.coordinates(index, 2), '.r');
                    app.SensorIndex = index;
                
            end
        end

        % Selection changed function: AnalysisButtonGroup
        function AnalysisButtonGroupSelectionChanged(app, event)
            selectedButton = app.AnalysisButtonGroup.SelectedObject;

            if strcmp(selectedButton.Text, 'Modal')
                app.SwitchAnalysisFields('off');
            else
                app.SwitchAnalysisFields('on');
            end
        end

        % Selection changed function: Crack2ButtonGroup
        function Crack2ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Crack2ButtonGroup.SelectedObject;
            app.Crack2 = str2double(selectedButton.Text(1:2));
        end

        % Selection changed function: Crack3ButtonGroup
        function Crack3ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Crack3ButtonGroup.SelectedObject;
            app.Crack3 = str2double(selectedButton.Text(1:2));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Sonkyobenchmark02UIFigure = uifigure;
            app.Sonkyobenchmark02UIFigure.Name = 'Sonkyo benchmark 0.2';
            app.SubmitButton = uibutton(app.Sonkyobenchmark02UIFigure, 'push');
            app.SubmitButton.Text = 'Submit';
            app.CloseButton = uibutton(app.Sonkyobenchmark02UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SelectmodelandanalysisPanel = uipanel(app.Sonkyobenchmark02UIFigure);
            app.SelectmodelandanalysisPanel.Title = 'Select model and analysis';
            app.ScenarioListBoxLabel = uilabel(app.SelectmodelandanalysisPanel);
            app.ScenarioListBoxLabel.Text = 'Scenario';
            app.ScenarioListBox = uilistbox(app.SelectmodelandanalysisPanel);
            app.ScenarioListBox.Items = {'Reference', 'Damage A', 'Damage B', 'Damage C', 'Damage D', 'Damage E', 'Damage F', 'Damage G', 'Damage H', 'Damage I', 'Damage J', 'Damage K', 'Damage L'};
            app.ScenarioListBox.ValueChangedFcn = createCallbackFcn(app, @ScenarioListBoxValueChanged, true);
            app.ScenarioListBox.Value = 'Reference';
            app.SensorsListBoxLabel = uilabel(app.SelectmodelandanalysisPanel);
            app.SensorsListBoxLabel.Text = 'Sensors';
            app.SensorsListBox = uilistbox(app.SelectmodelandanalysisPanel);
            app.SensorsListBox.Items = {'All sensors', 'Set-up 1', 'Set-up 2', 'Set-up 3', 'Set-up 4', 'Set-up 5'};
            app.SensorsListBox.ValueChangedFcn = createCallbackFcn(app, @SensorsListBoxValueChanged, true);
            app.SensorsListBox.Value = 'All sensors';
            app.Crack1ButtonGroup = uibuttongroup(app.SelectmodelandanalysisPanel);
            app.Crack1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Crack1ButtonGroupSelectionChanged, true);
            app.Crack1ButtonGroup.Title = 'Crack 1';
            app.Button = uiradiobutton(app.Crack1ButtonGroup);
            app.Button.Text = '20 %';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.Crack1ButtonGroup);
            app.Button_2.Text = '50 %';
            app.Button_3 = uiradiobutton(app.Crack1ButtonGroup);
            app.Button_3.Text = '80 %';
            app.Crack2ButtonGroup = uibuttongroup(app.SelectmodelandanalysisPanel);
            app.Crack2ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Crack2ButtonGroupSelectionChanged, true);
            app.Crack2ButtonGroup.Title = 'Crack 2';
            app.Button_4 = uiradiobutton(app.Crack2ButtonGroup);
            app.Button_4.Text = '20 %';
            app.Button_4.Value = true;
            app.Button_5 = uiradiobutton(app.Crack2ButtonGroup);
            app.Button_5.Text = '50 %';
            app.Button_6 = uiradiobutton(app.Crack2ButtonGroup);
            app.Button_6.Text = '80 %';
            app.Crack3ButtonGroup = uibuttongroup(app.SelectmodelandanalysisPanel);
            app.Crack3ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Crack3ButtonGroupSelectionChanged, true);
            app.Crack3ButtonGroup.Title = 'Crack 3';
            app.Button_7 = uiradiobutton(app.Crack3ButtonGroup);
            app.Button_7.Text = '20 %';
            app.Button_7.Value = true;
            app.Button_8 = uiradiobutton(app.Crack3ButtonGroup);
            app.Button_8.Text = '50 %';
            app.Button_9 = uiradiobutton(app.Crack3ButtonGroup);
            app.Button_9.Text = '80 %';
            app.DegreeofdamageLabel = uilabel(app.SelectmodelandanalysisPanel);
            app.DegreeofdamageLabel.Text = 'Degree of damage';
            app.AnalysisButtonGroup = uibuttongroup(app.SelectmodelandanalysisPanel);
            app.AnalysisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AnalysisButtonGroupSelectionChanged, true);
            app.AnalysisButtonGroup.Title = 'Analysis';
            app.ModalButton = uiradiobutton(app.AnalysisButtonGroup);
            app.ModalButton.Text = 'Modal';
            app.TimehistoryButton = uiradiobutton(app.AnalysisButtonGroup);
            app.TimehistoryButton.Text = 'Time history';
            app.TimehistoryButton.Value = true;
            app.OutputfileEditFieldLabel = uilabel(app.SelectmodelandanalysisPanel);
            app.OutputfileEditFieldLabel.Text = 'Output file';
            app.OutputfileEditField = uieditfield(app.SelectmodelandanalysisPanel, 'text');
            app.ModelpreviewPanel = uipanel(app.Sonkyobenchmark02UIFigure);
            app.ModelpreviewPanel.Title = 'Model preview';
            app.UIAxes = uiaxes(app.ModelpreviewPanel);
            xlabel(app.UIAxes, '[X mm]')
            ylabel(app.UIAxes, 'Y [mm]')
            app.SpecifyanalysissettingsPanel = uipanel(app.Sonkyobenchmark02UIFigure);
            app.SpecifyanalysissettingsPanel.Title = 'Specify analysis settings';
            app.OperationalconditionsLabel = uilabel(app.SpecifyanalysissettingsPanel);
            app.OperationalconditionsLabel.Text = 'Operational conditions';
            app.TemperatureCEditFieldLabel = uilabel(app.SpecifyanalysissettingsPanel);
            app.TemperatureCEditFieldLabel.Text = 'Temperature [C]';
            app.TemperatureCEditField = uieditfield(app.SpecifyanalysissettingsPanel, 'numeric');
            app.TemperatureCEditField.Limits = [-15 40];
            app.TemperatureCEditField.Value = 25;
            app.PitchangledegEditField_2Label = uilabel(app.SpecifyanalysissettingsPanel);
            app.PitchangledegEditField_2Label.Text = 'Pitch angle [deg]';
            app.PitchangledegEditField_2 = uieditfield(app.SpecifyanalysissettingsPanel, 'numeric');
            app.RotorspeedrpmEditField_2Label = uilabel(app.SpecifyanalysissettingsPanel);
            app.RotorspeedrpmEditField_2Label.Text = 'Rotor speed [rpm]';
            app.RotorspeedrpmEditField_2 = uieditfield(app.SpecifyanalysissettingsPanel, 'numeric');
            app.RotorspeedrpmEditField_2.Value = 10;
            app.WindspeedmsEditFieldLabel = uilabel(app.SpecifyanalysissettingsPanel);
            app.WindspeedmsEditFieldLabel.Text = 'Wind speed [m/s]';
            app.WindspeedmsEditField = uieditfield(app.SpecifyanalysissettingsPanel, 'numeric');
            app.WindspeedmsEditField.Value = 10;
            app.LoadseedEditFieldLabel = uilabel(app.SpecifyanalysissettingsPanel);
            app.LoadseedEditFieldLabel.Text = 'Load seed';
            app.LoadseedEditField = uieditfield(app.SpecifyanalysissettingsPanel, 'numeric');
            app.LoadseedEditField.Limits = [1 10];
            app.LoadseedEditField.Value = 1;
            app.TimeperiodEditFieldLabel = uilabel(app.SpecifyanalysissettingsPanel);
            app.TimeperiodEditFieldLabel.Text = 'Time period';
            app.TimeperiodEditField = uieditfield(app.SpecifyanalysissettingsPanel, 'numeric');
            app.TimeperiodEditField.Limits = [0 1800];
            app.TimeperiodEditField.Value = 60;
            app.StepsizeEditFieldLabel = uilabel(app.SpecifyanalysissettingsPanel);
            app.StepsizeEditFieldLabel.Text = 'Step size';
            app.StepsizeEditField = uieditfield(app.SpecifyanalysissettingsPanel, 'numeric');
            app.StepsizeEditField.Limits = [5e-07 1];
            app.StepsizeEditField.Value = 0.02;
            app.AlphaEditFieldLabel = uilabel(app.SpecifyanalysissettingsPanel);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.SpecifyanalysissettingsPanel, 'numeric');
            app.AlphaEditField.Value = 0.008;
            app.BetaEditFieldLabel = uilabel(app.SpecifyanalysissettingsPanel);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.SpecifyanalysissettingsPanel, 'numeric');
            app.TimehistorysettingsLabel = uilabel(app.SpecifyanalysissettingsPanel);
            app.TimehistorysettingsLabel.Text = 'Time history settings';
            app.DampingLabel = uilabel(app.SpecifyanalysissettingsPanel);
            app.DampingLabel.Text = 'Damping';
            app.MessagesPanel = uipanel(app.Sonkyobenchmark02UIFigure);
            app.MessagesPanel.Title = 'Messages';
            app.TextArea = uitextarea(app.MessagesPanel);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Sonkyo

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Sonkyobenchmark02UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Sonkyobenchmark02UIFigure)
        end
    end
end